classdef ModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SimulationTab                 matlab.ui.container.Tab
        FittingCTRMSTextAreaLabel     matlab.ui.control.Label
        FittingCTRMSTextArea          matlab.ui.control.TextArea
        CVFluxRMSTextAreaLabel        matlab.ui.control.Label
        CVFluxRMSTextArea             matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        EvaporatingDropFittingandUncertaintyAnalyzerLabel  matlab.ui.control.Label
        GridfitCheckBox               matlab.ui.control.CheckBox
        SpacingOptionButtonGroup      matlab.ui.container.ButtonGroup
        AnalyticalButton              matlab.ui.control.RadioButton
        ExperimentalButton            matlab.ui.control.RadioButton
        StartButton_2                 matlab.ui.control.Button
        SelectAllButton               matlab.ui.control.Button
        GridfitSmoothnessButtonGroup  matlab.ui.container.ButtonGroup
        SingleButton                  matlab.ui.control.RadioButton
        OptimizedButton               matlab.ui.control.RadioButton
        ClearButton                   matlab.ui.control.Button
        NoiseCheckBox                 matlab.ui.control.CheckBox
        ZLevelsListBoxLabel           matlab.ui.control.Label
        ZLevelsListBox                matlab.ui.control.ListBox
        SmoothnessEditFieldLabel      matlab.ui.control.Label
        SmoothnessEditField           matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        toEditField                   matlab.ui.control.NumericEditField
        byEditFieldLabel              matlab.ui.control.Label
        byEditField                   matlab.ui.control.NumericEditField
        PowerSpinnerLabel             matlab.ui.control.Label
        PowerSpinner                  matlab.ui.control.Spinner
        CompoundDropDownLabel         matlab.ui.control.Label
        CompoundDropDown              matlab.ui.control.DropDown
        FittingMethodDropDownLabel    matlab.ui.control.Label
        FittingMethodDropDown         matlab.ui.control.DropDown
        OptimizePolyfitCheckBox       matlab.ui.control.CheckBox
        ResetButton                   matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
    end


    methods (Access = public)
        
        function [compound,compvals] = ObtainCompound(app)
            compound = app.CompoundDropDown.Value;
            switch compound
                case '*methanol 3200-2500*'
                    value = 1;
                case 'methanol 1150-800'
                    value = 2;
                case '*hexane 3100-2800*'
                    value = 3;
                case 'hexane 1520-1420'
                    value = 4;
                case '*3MP 3100-2800*'
                    value = 5;
                case '3MP 1520-1420'
                    value = 6;
            end
            compvals = DetermineCompoundInfo(app,value);
        end
        
        function  value = DetermineCompoundInfo(~,val)
                  value = val;
        end
        
        function noise = enableNoise(app)
            noise = app.NoiseCheckBox.Value
        end
        
        %Obtaining Z-locations
        function z_loc = obtainZLocation(app)
            value = app.ZLevelsListBox.Value;
            z_loc = str2double(value)
           
        end
        
        %Obtaining Spacing Option
        function Spacing = obtainSpacing(app)
            selectedButton = app.SpacingOptionButtonGroup.SelectedObject;
            if selectedButton == app.AnalyticalButton
                Spacing = 'Analytical..';
            else
                Spacing = 'Experimental';
            end
        end
        
        %Obtaining Fitting Method
        function Fitting = obtainFittingMethod(app)
            Fitting = app.FittingMethodDropDown.Value   
        end
        
        %Asking if user wants to continue with gridfit
        function GridFit = GridFitVals(app)
            value = app.GridfitCheckBox.Value;
            if value == 1
                GridFit = 'Yes';
            else
                GridFit = 'No.';
            end
            
        end
        
        
        %Obtaining Smoothness
        function Smoothness = ObtainSmoothnessValues(app,GridFit)
            if GridFit == 'Yes'
                Smoothness = DetermineSmoothnessRange(app);
                
            else
                Smoothness = [];
            end
            
        end
        
        %Obtaining Smoothness Range
        function Smoothness = DetermineSmoothnessRange(app)
            selectedButton = app.GridfitSmoothnessButtonGroup.SelectedObject;
            if selectedButton == app.SingleButton
                Smoothness = app.SmoothnessEditField.Value;
            else
                Smoothness = app.SmoothnessEditField.Value:app.byEditField.Value:app.toEditField.Value;
            end
            
        end 
        
        %Obtaining Power Value
        function Power = ObtainPowerValue(app)
            Power = app.PowerSpinner.Value;
        end
        
        function OptimalPower = getObtimalPower(app)
            OptimalPower = app.OptimizePolyfitCheckBox.Value;
        end
        
        %Putting together all user inputs for calculations
        function rootdir = AquireParameters(app,compound,compoundValues,z_loc,SpacingOption,GridFit,Smoothness,OptimalPower,power,noise_flag,Fitting)
            clc
           
              
           app.FittingCTRMSTextArea.FontColor = [0, 0, 0];    
%             EfficientAnalysisGUI(compound,compoundValues,z_loc,SpacingOption,GridFit,Smoothness,Power,noise);
           [mu,compound,dirext,M,C_v,R] = CompoundInfo(compoundValues);
           [x_spacing] = getXSpace(SpacingOption); 
           [noise_IA,noiseEnabled] = getNoise(noise_flag);
           [rootdir] = makeDirectory(SpacingOption,OptimalPower, power,Fitting,noiseEnabled)               

           z_length = length(z_loc)
           [resultsOutput,new_results_Size] =  IA_and_CT(app,z_length,z_loc,mu,compound,dirext,M,C_v,R,x_spacing,noise_IA,rootdir,SpacingOption,OptimalPower,power,Fitting,noise_flag);
           
               app.FittingCTRMSTextArea.FontColor = [1, 0, 0];
              % app.FittingCTRMSTextArea.Value = {'An Error Has Occured'; 'See Command Line For Details'; 'Press Reset To Restart'};
               app.StartButton_2.Enable = 'off'
               app.ResetButton.Enable = 'on'
               pause('on');
           
            if GridFit == 'Yes'
               
               app.CVFluxRMSTextArea.FontColor = [0, 0, 0];    
               select_case = 2;
               resultpos = Format_Part1_to_Part2_mod(rootdir,resultsOutput,select_case,new_results_Size(1));
               Part2(app,M,C_v,rootdir,resultpos,Smoothness,noise_flag)
%                 path3 = Methanol_Analytical_12_29(app,rootdir,C_f, resultpos,power,Smoothness,sm_length,gs,noise_flag);
            
               app.ResetButton.Enable = 'on'  
               app.StartButton_2.Enable = 'off'
               app.CVFluxRMSTextArea.FontColor = [1, 0, 0];
              % app.CVFluxRMSTextArea.Value = {'An Error Has Occured'; 'See Command Line For Details'; 'Press Reset To Restart'};
               pause('on');
                   if isequal(app.ResetButton.Enable,'off')
                       
                       pause('off');
                   end
               
            end

        end
        
        
    end


    methods (Access = private)

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
           app.ZLevelsListBox.Value = {};
           
        end

        % Value changed function: FittingMethodDropDown
        function FittingMethodDropDownValueChanged(app, event)
            value = app.FittingMethodDropDown.Value;
            if  ~isequal(value,'Polyfit')
                app.PowerSpinner.Visible = 'off'
                app.PowerSpinnerLabel.Visible = 'off'
                app.OptimizePolyfitCheckBox.Visible = 'off'
                
            else 
                app.PowerSpinnerLabel.Visible = 'on'
                app.PowerSpinner.Visible = 'on'
                app.OptimizePolyfitCheckBox.Visible = 'on'
                
            end
            
            
        end

        % Value changed function: GridfitCheckBox
        function GridfitCheckBoxValueChanged(app, event)
                  
            value = app.GridfitCheckBox.Value;
            if value == 1
                app.SingleButton.Enable = 'on';
                app.OptimizedButton.Enable = 'on';
                app.SmoothnessEditFieldLabel.Enable = 'on';
                app.SmoothnessEditField.Enable = 'on';
                app.ZLevelsListBox.Value = {'1','2','3','4','5','6','8','10','15','20'};
                
            else
                app.SingleButton.Enable = 'off';
                app.OptimizedButton.Enable = 'off';
                app.SmoothnessEditFieldLabel.Enable = 'off';
                app.SmoothnessEditField.Enable = 'off';
                app.toEditField.Enable = 'off'
                app.toEditFieldLabel.Enable = 'off'
                app.byEditField.Enable = 'off'
                app.byEditFieldLabel.Enable = 'off'    
            end
        
            
        end

        % Selection changed function: GridfitSmoothnessButtonGroup
        function GridfitSmoothnessButtonGroupSelectionChanged(app, event)
            selectedButton = app.GridfitSmoothnessButtonGroup.SelectedObject;
            if selectedButton == app.SingleButton
                app.SmoothnessEditFieldLabel.Enable = 'on';
                app.SmoothnessEditField.Enable = 'on';
                app.toEditField.Enable = 'off'
                app.toEditFieldLabel.Enable = 'off'
                app.byEditField.Enable = 'off'
                app.byEditFieldLabel.Enable = 'off'                
            else
                app.SmoothnessEditFieldLabel.Enable = 'on';
                app.SmoothnessEditField.Enable = 'on';                
                app.toEditField.Enable = 'on';
                app.toEditFieldLabel.Enable = 'on';
                app.byEditField.Enable = 'on';
                app.byEditFieldLabel.Enable = 'on';
            end
        end

        % Value changed function: OptimizePolyfitCheckBox
        function OptimizePolyfitCheckBoxValueChanged(app, event)
            value = app.OptimizePolyfitCheckBox.Value;
            if value == 1
                app.PowerSpinnerLabel.Enable = 'off'
                app.PowerSpinner.Enable = 'off'
                app.ZLevelsListBox.Value = {'1','2','3','4','5','6','8','10','15','20'};
                
            else
                app.PowerSpinnerLabel.Enable = 'on'
                app.PowerSpinner.Enable = 'on'
                app.ZLevelsListBox.Value = {}; 
            end
                
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.StartButton_2.Enable = 'on';
            app.ResetButton.Enable = 'off';
            app.Lamp.Color = [0,1,0];
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
           app.ZLevelsListBox.Value = {'1','2','3','4','5','6','8','10','15','20'};
           
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            app.Lamp.Color = [1 0 0];
            app.CVFluxRMSTextArea.Value = {''};
            app.FittingCTRMSTextArea.Value = {''};
            app.StartButton_2.Enable = 'off';
            
            %Obtaining Compound
            
            [compound,compoundValues] = ObtainCompound(app);
            
            %Selecting Z-Locaticons
            z = obtainZLocation(app);
            
            %Selecting Spacing
            SpacingOption = obtainSpacing(app);
            
            %GetFitting
            Fitting = obtainFittingMethod(app);
            
            %Selecting Power
            Power = ObtainPowerValue(app);
            OptimalPower = getObtimalPower(app)
            
            %Selecting Noise
            noise = enableNoise(app);
            
            %Asking if user wants to continue with gridfit 
            GridFit = GridFitVals(app);
            
            %ObtainingSmoothnessValues
            Smoothness = ObtainSmoothnessValues(app,GridFit);
            app.UIAxes_2.NextPlot = 'replacechildren';
            %putting all the values together
            
            rootdir = AquireParameters(app,compound,compoundValues,z,SpacingOption,GridFit,Smoothness,OptimalPower,Power,noise,Fitting);
            
            app.Lamp.Color = [0 1 0];
            %
            %             d = dir; %get files
            app.StartButton_2.Enable = 'on';
            %             set(app.SavedFilesListBox,'String',{d.name})
            %             app.StartButton_2.Enable = 'on'
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.FittingCTRMSTextAreaLabel = uilabel(app.SimulationTab);
            app.FittingCTRMSTextAreaLabel.Text = 'Fitting/CT RMS';
            app.FittingCTRMSTextArea = uitextarea(app.SimulationTab);
            app.FittingCTRMSTextArea.Editable = 'off';
            app.CVFluxRMSTextAreaLabel = uilabel(app.SimulationTab);
            app.CVFluxRMSTextAreaLabel.Text = 'CV Flux RMS';
            app.CVFluxRMSTextArea = uitextarea(app.SimulationTab);
            app.CVFluxRMSTextArea.Editable = 'off';
            app.UIAxes = uiaxes(app.SimulationTab);
            title(app.UIAxes, 'Fitting Output')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.SimulationTab);
            title(app.UIAxes_2, 'CT output')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.EvaporatingDropFittingandUncertaintyAnalyzerLabel = uilabel(app.SimulationTab);
            app.EvaporatingDropFittingandUncertaintyAnalyzerLabel.Text = 'Evaporating Drop Fitting and Uncertainty Analyzer';
            app.GridfitCheckBox = uicheckbox(app.SimulationTab);
            app.GridfitCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridfitCheckBoxValueChanged, true);
            app.GridfitCheckBox.Text = 'Gridfit';
            app.SpacingOptionButtonGroup = uibuttongroup(app.SimulationTab);
            app.SpacingOptionButtonGroup.Title = 'Spacing Option';
            app.AnalyticalButton = uiradiobutton(app.SpacingOptionButtonGroup);
            app.AnalyticalButton.Text = 'Analytical';
            app.AnalyticalButton.Value = true;
            app.ExperimentalButton = uiradiobutton(app.SpacingOptionButtonGroup);
            app.ExperimentalButton.Text = 'Experimental';
            app.StartButton_2 = uibutton(app.SimulationTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.SelectAllButton = uibutton(app.SimulationTab, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.GridfitSmoothnessButtonGroup = uibuttongroup(app.SimulationTab);
            app.GridfitSmoothnessButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GridfitSmoothnessButtonGroupSelectionChanged, true);
            app.GridfitSmoothnessButtonGroup.Title = 'Gridfit Smoothness';
            app.SingleButton = uiradiobutton(app.GridfitSmoothnessButtonGroup);
            app.SingleButton.Enable = 'off';
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.OptimizedButton = uiradiobutton(app.GridfitSmoothnessButtonGroup);
            app.OptimizedButton.Enable = 'off';
            app.OptimizedButton.Text = 'Optimized';
            app.ClearButton = uibutton(app.SimulationTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.NoiseCheckBox = uicheckbox(app.SimulationTab);
            app.NoiseCheckBox.Text = 'Noise';
            app.ZLevelsListBoxLabel = uilabel(app.SimulationTab);
            app.ZLevelsListBoxLabel.Text = 'Z-Levels';
            app.ZLevelsListBox = uilistbox(app.SimulationTab);
            app.ZLevelsListBox.Items = {'1', '2', '3', '4', '5', '6', '8', '10', '15', '20'};
            app.ZLevelsListBox.Multiselect = 'on';
            app.ZLevelsListBox.Value = {'1'};
            app.SmoothnessEditFieldLabel = uilabel(app.SimulationTab);
            app.SmoothnessEditFieldLabel.Enable = 'off';
            app.SmoothnessEditFieldLabel.Text = 'Smoothness';
            app.SmoothnessEditField = uieditfield(app.SimulationTab, 'numeric');
            app.SmoothnessEditField.Limits = [1 Inf];
            app.SmoothnessEditField.ValueDisplayFormat = '%.1f';
            app.SmoothnessEditField.Enable = 'off';
            app.SmoothnessEditField.Value = 1;
            app.toEditFieldLabel = uilabel(app.SimulationTab);
            app.toEditFieldLabel.Enable = 'off';
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.SimulationTab, 'numeric');
            app.toEditField.Limits = [1 Inf];
            app.toEditField.ValueDisplayFormat = '%.1f';
            app.toEditField.Enable = 'off';
            app.toEditField.Value = 20;
            app.byEditFieldLabel = uilabel(app.SimulationTab);
            app.byEditFieldLabel.Enable = 'off';
            app.byEditFieldLabel.Text = 'by';
            app.byEditField = uieditfield(app.SimulationTab, 'numeric');
            app.byEditField.Limits = [0.1 Inf];
            app.byEditField.ValueDisplayFormat = '%.1f';
            app.byEditField.Enable = 'off';
            app.byEditField.Value = 0.5;
            app.PowerSpinnerLabel = uilabel(app.SimulationTab);
            app.PowerSpinnerLabel.Text = 'Power';
            app.PowerSpinner = uispinner(app.SimulationTab);
            app.PowerSpinner.Limits = [0 20];
            app.PowerSpinner.Value = 10;
            app.CompoundDropDownLabel = uilabel(app.SimulationTab);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.CompoundDropDown = uidropdown(app.SimulationTab);
            app.CompoundDropDown.Items = {'*methanol 3200-2500*', 'methanol 1150-800', '*hexane 3100-2800*', 'hexane 1520-1420', '*3MP 3100-2800*', '3MP 1520-1420'};
            app.CompoundDropDown.Value = '*methanol 3200-2500*';
            app.FittingMethodDropDownLabel = uilabel(app.SimulationTab);
            app.FittingMethodDropDownLabel.Text = 'Fitting Method';
            app.FittingMethodDropDown = uidropdown(app.SimulationTab);
            app.FittingMethodDropDown.Items = {'Polyfit', 'CubicSpline', 'gridfit'};
            app.FittingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FittingMethodDropDownValueChanged, true);
            app.FittingMethodDropDown.Value = 'Polyfit';
            app.OptimizePolyfitCheckBox = uicheckbox(app.SimulationTab);
            app.OptimizePolyfitCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimizePolyfitCheckBoxValueChanged, true);
            app.OptimizePolyfitCheckBox.Text = 'Optimize Polyfit';
            app.ResetButton = uibutton(app.SimulationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.Lamp = uilamp(app.SimulationTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ModelGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end